classdef Diatomix < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout               matlab.ui.container.GridLayout
        GridLayout2              matlab.ui.container.GridLayout
        TabGroup                 matlab.ui.container.TabGroup
        FieldsTab                matlab.ui.container.Tab
        GridLayout3              matlab.ui.container.GridLayout
        IntensityDirField        matlab.ui.control.EditField
        EDirField                matlab.ui.control.EditField
        BDirField                matlab.ui.control.EditField
        FieldLabel               matlab.ui.control.Label
        IntensityEditFieldLabel  matlab.ui.control.Label
        EVcmLabel                matlab.ui.control.Label
        BGLabel                  matlab.ui.control.Label
        DirectionLabel           matlab.ui.control.Label
        BField                   matlab.ui.control.EditField
        EField                   matlab.ui.control.EditField
        IntensityField           matlab.ui.control.EditField
        MoleculeTab              matlab.ui.container.Tab
        TextArea                 matlab.ui.control.TextArea
        MoleculeEditField        matlab.ui.control.EditField
        MoleculeLabel            matlab.ui.control.Label
        Tree                     matlab.ui.container.CheckBoxTree
        NuclearElectricNode      matlab.ui.container.TreeNode
        SpinRotationNode         matlab.ui.container.TreeNode
        SpinSpinTensorNode       matlab.ui.container.TreeNode
        SpinSpinScalarNode       matlab.ui.container.TreeNode
        RigidRotorNode           matlab.ui.container.TreeNode
        PlotTab                  matlab.ui.container.Tab
        ShowPointsCheckBox       matlab.ui.control.CheckBox
        Y2DropDown               matlab.ui.control.DropDown
        Y2DropDownLabel          matlab.ui.control.Label
        Y1DropDown               matlab.ui.control.DropDown
        Y1DropDownLabel          matlab.ui.control.Label
        PlotAdiabatsCheckBox     matlab.ui.control.CheckBox
        UITable                  matlab.ui.control.Table
        UIAxes                   matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Ham % Hamiltonian
        Molecule
        UCBasis % Uncoupled Basis
        selectStates % States to show
    end
    
    methods (Access = private)
        
        function updatePlot(comp)
            ud = comp.UserData;
            ax = comp.UIAxes;

            delete(findobj(ax,'Type','line'))
            delete(findobj(ax,'Type','ConstantLine'))
            delete(findobj(ax,'Type','Scatter'))
            delete(findobj(ax,'Tag','update'))
            spec = ud.spectrum(:,ud.selectStates);
            
            %Part for the first axis
            % yyaxis(ax,'left')
            % ax.YAxis(1).Color = "k";
            switch string(ud.currY1)
                case "E (MHz)"
                    y1 = spec;
                    set(ax, 'YDir','normal')
                case "E - <E> (MHz)"
                    y1 = spec - mean(spec,2);
                    set(ax, 'YDir','normal')
                case "µ"
                    if isempty(ud.muEffective)
                        [mu,muStates] = calculateMuEffective(comp);
                        ud.muEffective = mu;
                        ud.muStates = muStates;
                    end
                    y1 = ud.muEffective(:,ud.selectStates);
                    set(ax, 'YDir','reverse')
            end
            if ~isempty(y1)
                ps1 = plot(ax,ud.xVar.value,y1,'-', "Color",[1,1,1]*0.15, LineWidth=1);
                if comp.ShowPointsCheckBox.Value
                    hold(ax,'on');
                    plot(ax,ud.xVar.value,y1, ...
                        'o',MarkerSize=2, MarkerFaceColor=[1,1,1]*0.1, MarkerEdgeColor="none");
                    hold(ax,'off');
                end
            end
            % for p=1:length(ps)
            %     ps(p).DataTipTemplate(1).DataTipRows(2).Label="E(MHz)=";
            % %     ps(p).DataTipTemplate(1).DataTipRows(3).Label="E(MHz)=";
            % end
            xlabel(ax,sprintf("%s (%s)",ud.xVar.name,ud.xVar.unit))
            ylabel(ax,string(comp.Y1DropDown.Value))
            title(ax,comp.Molecule.name, Interpreter="tex")
            xlim(ax,[min(ud.xVar.value),max(ud.xVar.value)]);
            ylim(ax,'auto')
            legend(ax,'off');

            %Part for the second axis -- Not functional yet!
            % switch string(ud.currY2)
            %     case "E (MHz)"
            %         y2 = spec;
            %     case "E - <E> (MHz)"
            %         y2 = spec - mean(spec,2);
            %     case "µ"
            %         if isempty(ud.muEffective)
            %             mu = calculateMuEffective(comp);
            %             ud.muEffective = mu;
            %         end
            %         y2 = ud.muEffective(:,ud.selectStates);
            %         % y2=[];
            %     case "None"
            %         y2 = [];
            %         yyaxis(ax,'right')
            %         ax.YAxis(2).Visible='off';
            %         set(ax, 'YDir','reverse')
            % end
            % yyaxis(ax,'right')
            % if ~isempty(y2)
            %     ax.YAxis(2).Visible='on';
            %     ps2 = plot(ax,ud.xVar.value,y2, ...
            %     'o-', "Color","r",MarkerSize=2, LineWidth=1, MarkerFaceColor=[1,0,0], MarkerEdgeColor="none", Tag="Update");
            % end
            % xlim(ax,[min(ud.xVar.value),max(ud.xVar.value)]);
            % ylabel(ax,string(comp.Y2DropDown.Value))
            % 
            % ax.YAxis(1).Visible='on'; %Set focus back to left axis
            comp.UserData = ud;
        end
        function [adiabat, diabat, adiabatStates, diabatStates] = findDiabats(comp, xIdx,yIdx)
            %Checks adjacent states to determine least change in state
            ud = comp.UserData;
            cSpec = ud.spectrum;
            cStates = ud.currStates;
            adiabat = cSpec(:,yIdx); %follow xth energy state, stores energies
            adiabatStates = abs(squeeze(cStates(:,:,yIdx))).^2;
        
            diabatYidx = nan(1,length(ud.xVar.value)); %stores yIdx, not energy
            diabat = nan(1,length(ud.xVar.value)); %stores yIdx, not energy
        
            diabatYidx(xIdx) = yIdx;
            diabat(xIdx) = cSpec(xIdx,yIdx);
            diabatStates = nan(size(adiabatStates));
            diabatStates(xIdx,:) = (squeeze(cStates(xIdx,:,yIdx)));
            % [~, sIdx] = sortrows(diabatStates(xIdx,:)'.^2,"descend");
            adjC = 3;
            for k=xIdx:(length(ud.xVar.value)-1) %forward loop
                stateNow = abs(squeeze(ud.currStates(k,:,diabatYidx(k))));
                stateSearch =diabatYidx(k)-adjC:diabatYidx(k)+adjC; stateSearch(stateSearch<=0)=[];
                statesNext = abs(squeeze(ud.currStates(k+1,:,stateSearch)));
                [~,S]=sort(sum((statesNext.*stateNow'),1)); %computes overlap integral
                idx = stateSearch(S(end)); %restrict search to adjacent states
                if abs(idx-diabatYidx(k))>3
                    idx = stateSearch(S(end-1));
                end
                diabatYidx(k+1) = idx;
                diabat(k+1) = ud.spectrum(k+1,idx);
                diabatStates(k+1,:) = (squeeze(ud.currStates(k+1,:,idx)));
            end
            for k=(xIdx-1):-1:1 %backward loop
                stateIdx = diabatYidx(k+1);
                stateNow = abs(squeeze(cStates(k+1,:,stateIdx)));
                stateSearch =stateIdx-adjC:stateIdx+adjC; stateSearch(stateSearch<=0)=[];
                statesPrevious = abs(squeeze(cStates(k,:,stateSearch))); 
                [~,S]=sort(sum((statesPrevious.*stateNow'),1)); %maximum overlap
                idx = stateSearch(S(end));
                if abs(idx-stateIdx)>3
                    idx = stateSearch(S(end-1));
                end
                diabatYidx(k) = idx;
                diabat(k) = cSpec(k,idx);
                diabatStates(k,:) = (squeeze(cStates(k,:,idx)));
            end      
            % diabatStates = diabatStates(:,sIdx);
        end
        function calculateSpectrum(comp)
            C = Constants;
            ud = comp.UserData;
            Ham = comp.Ham;
            nStates = Ham.Basis.NStates;
            energies = nan(length(ud.xVar.value),nStates);
            states = nan(length(ud.xVar.value),nStates,nStates);
            E.dir = ud.E.dir;
            B.dir = ud.B.dir;
            for k = 1:length(ud.xVar.value)
                if strcmp(ud.xVar.name,"B")
                    E.value = ud.E.value(1)*ud.E.scale; 
                    B.value = ud.B.value(k)*ud.B.scale;
                    H = Ham.makeHamiltonian(E=E,B=B);
                elseif strcmp(ud.xVar.name,"E")
                    E.value = ud.E.value(k)*ud.E.scale;
                    B.value = ud.B.value(1)*ud.B.scale;
                    H = Ham.makeHamiltonian(E=E,B=B);
                else
                end
                [V,D] = eig(full(H/C.h),'vector'); %no need to use sparse matrices for matrices smaller than 1000x1000. Divide by Plancks constant to get energy in Hz
                [d,sortIdx] = sort(real(D));
                energies(k,:) = d;        
                states(k,:,:) = V(:,sortIdx);
            end
            ud.currStates = states;
            ud.spectrum = energies*1e-6; %energies in MHz (xVar,States)
            ud.muEffective = [];
            ud.muStates = [];
            comp.UserData = ud;
        end
        function updateHamiltonian(comp)
            ud = comp.UserData;

            Ham = Hamiltonian(Molecule = comp.Molecule);
            comp.Ham = Ham; 

            UCBasis = Ham.Basis;
            statesUCBasis = UCBasis.getStates('all');
            stateNames = ["N","mN","mi1","mi2"];
            ud.statesUCBasis = statesUCBasis(:,stateNames);
            comp.UITable.ColumnName = ["Comp",stateNames];
            selectN=0;
            ud.selectStates = ismember(statesUCBasis.N,selectN);
            
            ud.muEffective = [];
            comp.UserData = ud;
        end
        function [mu, muStates] = calculateMuEffective(comp)
            %Not so trivial, requires first to calculate the diabats for
            %every state. Only do for selected number of states (N=0
            %manifold for example)
            ud = comp.UserData;
            base = ud.statesUCBasis;
            C = Constants;
            Mol = comp.Molecule;
            Atom1 = Mol.Atom1; Atom2 = Mol.Atom2;
            g1 = Atom1.gFactor; sig1 = Atom1.nuclearShielding;
            g2 = Atom2.gFactor; sig2 = Atom2.nuclearShielding;
            
            mu = nan(size(ud.spectrum(:,ud.selectStates)));
            muStates = nan(size(ud.currStates(:,:,ud.selectStates)));
            for stIdx = 1:size(mu,2)
                [~, ~, ~, diabatStates] = findDiabats(comp, size(mu,1),stIdx); %start from last state
                st = abs(diabatStates).^2;
                mu(:,stIdx) = g1*(1-sig1)*(st*base.mi1)+...
                    g2*(1-sig2)*(st*base.mi2) + Mol.gr*(st*base.mN); %in units of µN
                muStates(:,:,stIdx) = diabatStates;
            end
            % [mu,S] = sort(mu,2);

            % ud.currStates = ud.currStates(:,:);
            ud.muEffective = mu;
            ud.muStates = muStates;
            comp.UserData = ud;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initialise(comp)
                % fig = comp.Parent;
                addpath("Atoms\")
                addpath("Molecules\")
                
                ud = comp.UserData;
                ud.B.name = "B"; ud.B.unit = "G"; ud.B.scale = 1e-4; %from Tesla to Gauss
                ud.E.name = "E"; ud.E.unit = "V/cm"; ud.E.scale = 1e2; %from V/m to V/cm
                ud.B.dir = eval(comp.BDirField.Value);
                ud.E.dir = eval(comp.EDirField.Value);
                ud.I.dir = eval(comp.IntensityDirField.Value);
                ud.currY1 = comp.Y1DropDown.Value;
                ud.currY2 = comp.Y2DropDown.Value;
                comp.UserData = ud;

                comp.Molecule = KRb(40,87);
                updateHamiltonian(comp);
                % collisionE = 0.1; %MHz

                FieldValueChanged(comp); %gets field values and updates plot

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(comp, event)
            ud = comp.UserData;
            ax = comp.UIAxes;
            delete(findobj(ax,'Tag','update'))
            
            if strcmp(ud.currY1,"µ")
                cSpec = ud.muEffective;
                cStates = ud.muStates;
            else
                cSpec = ud.spectrum;
                cStates = ud.currStates;
            end

            %snap to nearest point and highlight
            [~,xIdx] = min(abs(event.IntersectionPoint(1)-ud.xVar.value));
            [~,yIdx] = min(abs(event.IntersectionPoint(2)-cSpec(xIdx,:)));
            ax.NextPlot ="add";
            xline(ax,ud.xVar.value(xIdx),'--',color="k", Tag="update")
            yline(ax,cSpec(xIdx,yIdx),'--',color="k",Tag="update")
            scatter(ax,ud.xVar.value(xIdx), cSpec(xIdx,yIdx),50,'green','filled','o', MarkerFaceAlpha=0.5, Tag="update")
            
            %retrieve state composition of point and update table
            stateComp = round(squeeze(cStates(xIdx,:,yIdx)).^2,4);
            [statesCompTable, ~] = sortrows([array2table(stateComp',"VariableNames","Comp") , ud.statesUCBasis], "Comp","descend");
            comp.UITable.Data = statesCompTable(1:20,:);

            %find diabat and adiabat and highlight 
            if comp.PlotAdiabatsCheckBox.Value
                if ~strcmp(ud.currY1, "µ")
                [adiabat, diabat] = findDiabats(comp,xIdx,yIdx);
                diabatLine = plot(ax,ud.xVar.value, diabat, '--', Color="red", LineWidth=1.5, ...
                    DisplayName="Diabat",Tag="update");
                adiabatLine = plot(ax,ud.xVar.value, adiabat, '--', Color="blue",LineWidth=1.5, ...
                    DisplayName="Adiabat",Tag="update");
                legend(ax,[adiabatLine,diabatLine], Location="northeast");
                else
                diabatLine = plot(ax,ud.xVar.value, ud.muEffective(:,yIdx), '-', Color="red", LineWidth=1.5, ...
                    DisplayName="Diabat",Tag="update");   
                legend(ax,[diabatLine], Location="northeast");
                end
            end
        end

        % Value changed function: BField, EField, IntensityField
        function FieldValueChanged(comp, event)
            ud = comp.UserData;
            try 
                ud.B.value = eval(comp.BField.Value);
                ud.E.value = eval(comp.EField.Value);
                % ud.I.value = eval(comp.comp.Intensity.Value);
                if length(ud.B.value)>1
                    ud.xVar = ud.B;
                elseif length(ud.E.value)>1
                    ud.xVar = ud.E;
                else
                    return
                end
            catch
                error("Not a valid entry for fields!")
                return
            end
            NPoints = length(ud.B.value)*length(ud.E.value);
            if NPoints > 302
                warning(sprintf("Do you really want to calculate %d points?",NPoints))
                return
            end
            comp.UserData = ud;
            calculateSpectrum(comp);
            updatePlot(comp);            
        end

        % Value changed function: BDirField, EDirField, IntensityDirField
        function FieldDirectionChanged(comp, event)
            ud = comp.UserData;
            % Bdir = eval(comp.BDirField.Value);
            Edir = eval(comp.EDirField.Value);
            Idir = eval(comp.IntensityDirField.Value);
            % assert(length(Bdir)==3 && sum(Bdir)~=0);
            assert(length(Edir)==3 && sum(Edir)~=0);
            assert(length(Idir)==3 && sum(Idir)~=0);

            % ud.B.dir=Bdir/norm(Bdir); 
            ud.E.dir=Edir/norm(Edir); 
            ud.I.dir=Idir/norm(Idir); 

            comp.UserData = ud;
            calculateSpectrum(comp);
            updatePlot(comp);   
        end

        % Value changed function: ShowPointsCheckBox
        function updatePlotCallback(comp, event)
            updatePlot(comp);   
        end

        % Value changed function: MoleculeEditField
        function MoleculeEditFieldValueChanged(comp, event)
            value = comp.MoleculeEditField.Value;
            try
                molecule = eval(value);
            catch
                error(sprintf("%s is not a valid Molecule Class", value));
            end
            comp.Molecule = molecule;
            updateHamiltonian(comp);
            calculateSpectrum(comp);
            updatePlot(comp); 
        end

        % Value changed function: Y1DropDown, Y2DropDown
        function YAxisChanged(comp, event)
            ud = comp.UserData;
            ud.currY1 = comp.Y1DropDown.Value;
            ud.currY2 = comp.Y2DropDown.Value;
            if strcmp(ud.currY1,"µ") && isempty(ud.muEffective)
                [mu, muStates] = calculateMuEffective(comp);
                ud.muEffective = mu;
                ud.muStates = muStates;
            end
            comp.UserData = ud;
            updatePlot(comp);   
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
           
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 918 559];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.UIAxes = uiaxes(comp.GridLayout);
            xlabel(comp.UIAxes, 'X')
            ylabel(comp.UIAxes, 'E (MHz)')
            zlabel(comp.UIAxes, 'Z')
            comp.UIAxes.ButtonDownFcn = matlab.apps.createCallbackFcn(comp, @UIAxesButtonDown, true);
            comp.UITable = uitable(comp.GridLayout);
            comp.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            comp.UITable.RowName = {};
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.TabGroup = uitabgroup(comp.GridLayout2);
            comp.FieldsTab = uitab(comp.TabGroup);
            comp.FieldsTab.Title = 'Fields';
            comp.GridLayout3 = uigridlayout(comp.FieldsTab);
            comp.IntensityField = uieditfield(comp.GridLayout3, 'text');
            comp.IntensityField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FieldValueChanged, true);
            comp.IntensityField.Value = '0';
            comp.EField = uieditfield(comp.GridLayout3, 'text');
            comp.EField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FieldValueChanged, true);
            comp.EField.Value = '0';
            comp.BField = uieditfield(comp.GridLayout3, 'text');
            comp.BField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FieldValueChanged, true);
            comp.BField.Value = '0:50';
            comp.DirectionLabel = uilabel(comp.GridLayout3);
            comp.DirectionLabel.Text = 'Direction';
            comp.BGLabel = uilabel(comp.GridLayout3);
            comp.BGLabel.Text = 'B (G)';
            comp.EVcmLabel = uilabel(comp.GridLayout3);
            comp.EVcmLabel.Text = 'E (V/cm)';
            comp.IntensityEditFieldLabel = uilabel(comp.GridLayout3);
            comp.IntensityEditFieldLabel.Text = 'Intensity';
            comp.FieldLabel = uilabel(comp.GridLayout3);
            comp.FieldLabel.Text = 'Field';
            comp.BDirField = uieditfield(comp.GridLayout3, 'text');
            comp.BDirField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FieldDirectionChanged, true);
            comp.BDirField.Editable = 'off';
            comp.BDirField.Value = '[0,0,1]';
            comp.EDirField = uieditfield(comp.GridLayout3, 'text');
            comp.EDirField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FieldDirectionChanged, true);
            comp.EDirField.Value = '[0,1,0]';
            comp.IntensityDirField = uieditfield(comp.GridLayout3, 'text');
            comp.IntensityDirField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FieldDirectionChanged, true);
            comp.IntensityDirField.Value = '[0,0,1]';
            comp.MoleculeTab = uitab(comp.TabGroup);
            comp.MoleculeTab.Title = 'Molecule';
            comp.Tree = uitree(comp.MoleculeTab, 'checkbox');
            comp.RigidRotorNode = uitreenode(comp.Tree);
            comp.RigidRotorNode.Text = 'Rigid Rotor';
            comp.SpinSpinScalarNode = uitreenode(comp.Tree);
            comp.SpinSpinScalarNode.Text = 'Spin-Spin (Scalar)';
            comp.SpinSpinTensorNode = uitreenode(comp.Tree);
            comp.SpinSpinTensorNode.Text = 'Spin-Spin (Tensor)';
            comp.SpinRotationNode = uitreenode(comp.Tree);
            comp.SpinRotationNode.Text = 'Spin-Rotation';
            comp.NuclearElectricNode = uitreenode(comp.Tree);
            comp.NuclearElectricNode.Text = 'Nuclear Electric';
            % Assign Checked Nodes
            comp.Tree.CheckedNodes = [comp.RigidRotorNode, comp.SpinSpinScalarNode, comp.SpinSpinTensorNode, comp.SpinRotationNode, comp.NuclearElectricNode];
            comp.MoleculeLabel = uilabel(comp.MoleculeTab);
            comp.MoleculeLabel.Text = 'Molecule:';
            comp.MoleculeEditField = uieditfield(comp.MoleculeTab, 'text');
            comp.MoleculeEditField.CharacterLimits = [0 15];
            comp.MoleculeEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @MoleculeEditFieldValueChanged, true);
            comp.MoleculeEditField.Placeholder = 'Molecule';
            comp.MoleculeEditField.Value = 'KRb(40,87)';
            comp.TextArea = uitextarea(comp.MoleculeTab);
            comp.PlotTab = uitab(comp.TabGroup);
            comp.PlotTab.Title = 'Plot';
            comp.PlotAdiabatsCheckBox = uicheckbox(comp.PlotTab);
            comp.PlotAdiabatsCheckBox.Text = 'Plot Adiabats';
            comp.Y1DropDownLabel = uilabel(comp.PlotTab);
            comp.Y1DropDownLabel.Text = 'Y1';
            comp.Y1DropDown = uidropdown(comp.PlotTab);
            comp.Y1DropDown.Items = {'E (MHz)', 'E - <E> (MHz)', 'µ'};
            comp.Y1DropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @YAxisChanged, true);
            comp.Y1DropDown.Value = 'E (MHz)';
            comp.Y2DropDownLabel = uilabel(comp.PlotTab);
            comp.Y2DropDownLabel.Enable = 'off';
            comp.Y2DropDownLabel.Visible = 'off';
            comp.Y2DropDownLabel.Text = 'Y2';
            comp.Y2DropDown = uidropdown(comp.PlotTab);
            comp.Y2DropDown.Items = {'E (MHz)', 'E - <E> (MHz)', 'µ', 'None'};
            comp.Y2DropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @YAxisChanged, true);
            comp.Y2DropDown.Enable = 'off';
            comp.Y2DropDown.Visible = 'off';
            comp.Y2DropDown.Value = 'None';
            comp.ShowPointsCheckBox = uicheckbox(comp.PlotTab);
            comp.ShowPointsCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updatePlotCallback, true);
            comp.ShowPointsCheckBox.Text = 'Show Points';
            % Execute the startup function
            initialise(comp)
        end
    end
end